classdef SSAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SSARUIFigure                  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ScenarioTab                   matlab.ui.container.Tab
        ScenarioButtonGroup           matlab.ui.container.ButtonGroup
        AButton                       matlab.ui.control.ToggleButton
        BButton                       matlab.ui.control.ToggleButton
        CButton                       matlab.ui.control.ToggleButton
        ClassifierADropDownLabel      matlab.ui.control.Label
        ClassifierADropDown           matlab.ui.control.DropDown
        ClassifierBDropDownLabel      matlab.ui.control.Label
        ClassifierBDropDown           matlab.ui.control.DropDown
        ClassifierCDropDownLabel      matlab.ui.control.Label
        ClassifierCDropDown           matlab.ui.control.DropDown
        DatasetpartitioningEditFieldLabel  matlab.ui.control.Label
        DatasetpartitioningEditField  matlab.ui.control.NumericEditField
        ModelsListBoxLabel            matlab.ui.control.Label
        ModelsListBox                 matlab.ui.control.ListBox
        RemoveButton                  matlab.ui.control.Button
        TestMultiModelButton          matlab.ui.control.Button
        RemoveWorstButton             matlab.ui.control.Button
        ErrorLimitLabel               matlab.ui.control.Label
        ErrorLimitEditField           matlab.ui.control.NumericEditField
        FeatureSelectionTab           matlab.ui.container.Tab
        RemoverawmeasurementsSwitchLabel  matlab.ui.control.Label
        RemoverawmeasurementsSwitch   matlab.ui.control.Switch
        RemovecorrelatedSwitchLabel   matlab.ui.control.Label
        RemovecorrelatedSwitch        matlab.ui.control.Switch
        MaximumCorrelationEditFieldLabel  matlab.ui.control.Label
        MaximumCorrelationEditField   matlab.ui.control.NumericEditField
        FeaturesListBoxLabel          matlab.ui.control.Label
        FeaturesListBox               matlab.ui.control.ListBox
        UpdateButton                  matlab.ui.control.Button
        GadgetButtonGroup             matlab.ui.container.ButtonGroup
        PhoneButton                   matlab.ui.control.ToggleButton
        WatchButton                   matlab.ui.control.ToggleButton
        SensorButtonGroup             matlab.ui.container.ButtonGroup
        AccelButton                   matlab.ui.control.ToggleButton
        GyroButton                    matlab.ui.control.ToggleButton
        FeatureRankingDropDownLabel   matlab.ui.control.Label
        FeatureRankingDropDown        matlab.ui.control.DropDown
        FeatureReductionTab           matlab.ui.container.Tab
        PCAVarianceEditFieldLabel     matlab.ui.control.Label
        PCAVarianceEditField          matlab.ui.control.NumericEditField
        LDAFeaturesEditFieldLabel     matlab.ui.control.Label
        LDAFeaturesEditField          matlab.ui.control.NumericEditField
        DecisionTreeTab               matlab.ui.container.Tab
        StartButton                   matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        AvgErrorEditFieldLabel        matlab.ui.control.Label
        AvgErrorEditField             matlab.ui.control.NumericEditField
        StdDevEditFieldLabel          matlab.ui.control.Label
        StdDevEditField               matlab.ui.control.NumericEditField
        MethodDropDownLabel           matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        PCAFeaturesEditFieldLabel     matlab.ui.control.Label
        PCAFeaturesEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        data_A %Scenario A data
        data_B %Scenario B data
        data_C %Scenario C data
        working_data %pre reduction data
        Amodels
        Bmodels
        Cmodels
    end
    
    methods (Access = private)
        
        function save_app(app)
            working_data = app.working_data;
            Amodels = app.Amodels;
            Bmodels = app.Bmodels;
            Cmodels = app.Cmodels;
            save("appData.mat", "working_data", "Amodels", "Bmodels", "Cmodels");
        end
        
        function load_app(app)
            try
                load("appData.mat");
                app.working_data = working_data;
                app.Amodels = Amodels;
                app.Bmodels = Bmodels;
                app.Cmodels = Cmodels;
            catch
                fprintf("No Save Data");
            end
        end
        
        function update_models(app)
            %choose models
            switch app.ScenarioButtonGroup.SelectedObject.Text
                case "A"
                    models = app.Amodels;
                case "B"
                    models = app.Bmodels;
                case "C"
                    models = app.Cmodels;   
            end
            items = strings(length(models),1);
            %display models
            for i = 1:length(models)
                items(i) = sprintf("%d|set: %d|%s|error: %.1f%%|std: %.1f%%", i, models{i}.set, models{i}.name, models{i}.avg_error*100, models{i}.std_error*100);
            end
            app.ModelsListBox.Items = items;
            if isempty(items)
                app.TestMultiModelButton.Enable = 'Off';
            else
                app.TestMultiModelButton.Enable = 'On';
            end
            app.save_app();
        end
        
        function resulting_model = ScenarioA(app, data)
            
            %fileID = fopen ('tests/results.txt','a');
            %formatSpec = '%s %s,%s,%.5f,%.5f\n';
            
            training = app.DatasetpartitioningEditField.Value;
            testing = 1-training;
            
            
            switch app.ClassifierADropDown.Value
                case "Euclidean"
                    [resulting_model, average_error, standard_dev] = appEuclideanA(app, data, 25, training, testing, 2);
                    disp(resulting_model);
                    save ("classifiers\euclidean_modelA.mat", "resulting_model");
                    resulting_model.name = "euclidean_model";
                case "Mahalanobis"
                    [resulting_model, average_error, standard_dev] = appMahalanobisA(app, data, 25, training, testing, 2);
                    disp(resulting_model);
                    save ("classifiers\mahalanobis_modelA.mat","resulting_model");
                    resulting_model.name = "mahalanobis_model";
                case "Fisher LDA"
                    [resulting_model, average_error, standard_dev] = appFisherLDA(app, data, 25, training, testing);
                    disp(resulting_model);
                    save ("classifiers\fisher_modelA.mat","resulting_model");
                    resulting_model.name = "fisher_model";
                case "Bayes"
                    [resulting_model, average_error, standard_dev] = appBayes(app, data, 25, training, testing, 2);
                    disp(resulting_model);
                    save ("classifiers\bayes_modelA.mat","resulting_model");
                    resulting_model.name = "bayes_model";
                case "K-means"
                    [resulting_model, average_error, standard_dev] = appKMeans(app, data, 25, training, testing, 2);
                    disp(resulting_model);
                    save("classifiers\kmeans_modelA.mat","resulting_model");
                    resulting_model.name = "kmeans_model";
                case "SVM"
                    [resulting_model, average_error, standard_dev] = appSVM(app, data, 25, training, testing, 2);
                    disp(resulting_model);
                    save("classifiers\svm_modelA.mat","resulting_model");
                    resulting_model.name = "svm_model";
            end


            %fprintf(fileID,formatSpec, app.GadgetButtonGroup.SelectedObject.Text, app.SensorButtonGroup.SelectedObject.Text,app.ClassifierADropDown.Value, average_error, standard_dev);
            
            resulting_model.avg_error = average_error;
            resulting_model.std_error = standard_dev;
            
            app.AvgErrorEditField.Value=average_error;
            app.StdDevEditField.Value=standard_dev;
            
            %fclose(fileID);
            
        end
        
        function resulting_model = ScenarioB(app, data)
            %fileID = fopen ('tests/results.txt','a');
            %formatSpec = '%s %s,%s,%.5f,%.5f\n';
            
            training = app.DatasetpartitioningEditField.Value;
            testing = 1-training;
            
            
            switch app.ClassifierBDropDown.Value
                case "Euclidean"
                    [resulting_model, average_error, standard_dev] = appEuclideanA(app, data, 25, training, testing, 3);
                    disp(resulting_model);
                    save ("classifiers\euclidean_modelB.mat", "resulting_model");
                    resulting_model.name = "euclidean_model";
                case "Mahalanobis"
                    [resulting_model, average_error, standard_dev] = appMahalanobisA(app, data, 25, training, testing, 3);
                    disp(resulting_model);
                    save ("classifiers\mahalanobis_modelB.mat","resulting_model");
                    resulting_model.name = "mahalanobis_model";
%                 case "Fisher LDA"
%                     [fisher_model, average_error, standard_dev] = appFisherLDA(app, data, 25, training, testing);
%                     disp(fisher_model)
%                     save ("classifiers\fisher_modelA.mat","fisher_model");
                case "Bayes"
                    [resulting_model, average_error, standard_dev] = appBayes(app, data, 25, training, testing, 3);
                    disp(resulting_model);
                    save ("classifiers\bayes_modelB.mat","resulting_model");
                    resulting_model.name = "bayes_model";
                case "K-means"
                    [resulting_model, average_error, standard_dev] = appKMeans(app, data, 25, training, testing, 3);
                    disp(resulting_model);
                    save("classifiers\kmeans_modelB.mat","resulting_model");
                    resulting_model.name = "kmeans_model";
                case "SVM"
                    [resulting_model, average_error, standard_dev] = appSVM(app, data, 25, training, testing, 3);
                    disp(resulting_model);
                    save("classifiers\svm_modelB.mat","resulting_model");
                    resulting_model.name = "svm_model";
            end


            %fprintf(fileID,formatSpec, app.GadgetButtonGroup.SelectedObject.Text, app.SensorButtonGroup.SelectedObject.Text,app.ClassifierADropDown.Value, average_error, standard_dev);
            resulting_model.avg_error = average_error;
            resulting_model.std_error = standard_dev;
            
            app.AvgErrorEditField.Value=average_error;
            app.StdDevEditField.Value=standard_dev;
            
            %fclose(fileID);
        end
        
        function resulting_model = ScenarioC(app, data)
            training = app.DatasetpartitioningEditField.Value;
            testing = 1-training;
            
            
            switch app.ClassifierCDropDown.Value
                case "Euclidean"
                    [resulting_model, average_error, standard_dev] = appEuclideanA(app, data, 25, training, testing, 18);
                    disp(resulting_model);
                    save ("classifiers\euclidean_modelC.mat", "resulting_model");
                    resulting_model.name = "euclidean_model";
                case "Mahalanobis"
                    [resulting_model, average_error, standard_dev] = appMahalanobisA(app, data, 25, training, testing, 18);
                    disp(resulting_model);
                    save ("classifiers\mahalanobis_modelC.mat","resulting_model");
                    resulting_model.name = "mahalanobis_model";
%                 case "Fisher LDA"
%                     [fisher_model, average_error, standard_dev] = appFisherLDA(app, data, 25, training, testing);
%                     disp(fisher_model)
%                     save ("classifiers\fisher_modelA.mat","fisher_model");
                case "Bayes"
                    [resulting_model, average_error, standard_dev] = appBayes(app, data, 25, training, testing, 18);
                    disp(resulting_model);
                    save ("classifiers\bayes_modelC.mat","resulting_model");
                    resulting_model.name = "bayes_model";
                case "K-means"
                    [resulting_model, average_error, standard_dev] = appKMeans(app, data, 25, training, testing, 18);
                    disp(resulting_model);
                    save("classifiers\kmeans_modelC.mat","resulting_model");
                    resulting_model.name = "kmeans_model";
                case "SVM"
                    [resulting_model, average_error, standard_dev] = appSVM(app, data, 25, training, testing, 18);
                    disp(resulting_model);
                    save("classifiers\svm_modelC.mat","resulting_model");
                    resulting_model.name = "svm_model";
            end


            %fprintf(fileID,formatSpec, app.GadgetButtonGroup.SelectedObject.Text, app.SensorButtonGroup.SelectedObject.Text,app.ClassifierADropDown.Value, average_error, standard_dev);
            resulting_model.avg_error = average_error;
            resulting_model.std_error = standard_dev;
            
            app.AvgErrorEditField.Value=average_error;
            app.StdDevEditField.Value=standard_dev;
            
            %fclose(fileID);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            if ~exist("tests", 'dir')
                mkdir("tests")
            end
            if ~exist("classifiers", 'dir')
                mkdir("classifiers")
            end
            [app.data_A, app.data_B, app.data_C] = appStartup();
            app.working_data = app.data_A(1);
            app.Amodels = cell(0);
            app.Bmodels = cell(0);
            app.Cmodels = cell(0); 
            app.load_app();
            app.update_models();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            checks = [strcmp(app.GadgetButtonGroup.SelectedObject.Text, 'Phone') strcmp(app.SensorButtonGroup.SelectedObject.Text, 'Accel')];
            if checks(1), index = 1; else, index = 3; end
            if ~checks(2), index = index + 1; end
            app.AvgErrorEditField.Value = -1;
            app.StartButton.Enable='off';
            data = app.working_data;
            features = app.FeaturesListBox.Value;
            if ~isempty(features)
                indices = zeros(length(features), 1);
                for i = 1:length(features)
                    indices(i) = find(contains(app.FeaturesListBox.Items, features{i}));
                end
                data.X = data.X(indices, :);
                data.label = data.label(indices);
                data.dim = length(indices);
                data.indices = data.indices(indices);
            end
            
            switch app.MethodDropDown.Value
                case "PCA #feat"
                    [data, reduction_model] = pcaReduceFeatures(data, app.PCAFeaturesEditField.Value);
                case "LDA"
                    [data, reduction_model] = ldaReduceFeatures(data, app.LDAFeaturesEditField.Value);
                case "PCA %var"
                    [data, reduction_model] = pcaReduceFeatures(data, app.PCAVarianceEditField.Value);
                case "None"
                    reduction_model = eye(size(data.X,1));
            end
            
            switch app.ScenarioButtonGroup.SelectedObject.Text
                case 'A'
                    app.Amodels{length(app.Amodels)+1,1} =  app.ScenarioA(data);
                    app.Amodels{length(app.Amodels),1}.indices = data.indices;
                    app.Amodels{length(app.Amodels),1}.reduction_model = reduction_model;
                    app.Amodels{length(app.Amodels),1}.set = index;
                case 'B'
                    app.Bmodels{length(app.Bmodels)+1,1} =  app.ScenarioB(data);
                    app.Bmodels{length(app.Bmodels),1}.indices = data.indices;
                    app.Bmodels{length(app.Bmodels),1}.reduction_model = reduction_model;
                    app.Bmodels{length(app.Bmodels),1}.set = index;
                case 'C'
                    app.Cmodels{length(app.Cmodels)+1,1} =  app.ScenarioC(data);
                    app.Cmodels{length(app.Cmodels),1}.indices = data.indices;
                    app.Cmodels{length(app.Cmodels),1}.reduction_model = reduction_model;
                    app.Cmodels{length(app.Cmodels),1}.set = index;
            end
            
            app.StartButton.Enable='on';
            app.update_models();
        end

        % Selection changed function: ScenarioButtonGroup
        function ScenarioButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScenarioButtonGroup.SelectedObject;
            if selectedButton.Text == 'A'
                app.working_data = app.data_A;
            elseif selectedButton.Text == 'B'
                app.working_data = app.data_B;
            else
                app.working_data = app.data_C;
            end
            app.StartButton.Enable = false;
            app.update_models();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            checks = [strcmp(app.GadgetButtonGroup.SelectedObject.Text, 'Phone') strcmp(app.SensorButtonGroup.SelectedObject.Text, 'Accel')];
            autoSelectMethod = app.FeatureRankingDropDown.Value;
            if app.ScenarioButtonGroup.SelectedObject.Text == 'A'
                [app.working_data, items, indices] = appFeatureSelection(app.data_A, app.RemoverawmeasurementsSwitch.Value, app.RemovecorrelatedSwitch.Value, autoSelectMethod, app.MaximumCorrelationEditField.Value, checks);
            elseif app.ScenarioButtonGroup.SelectedObject.Text == 'B'
                [app.working_data, items, indices] = appFeatureSelection(app.data_B, app.RemoverawmeasurementsSwitch.Value, app.RemovecorrelatedSwitch.Value, autoSelectMethod, app.MaximumCorrelationEditField.Value, checks);
            elseif app.ScenarioButtonGroup.SelectedObject.Text == 'C'
                [app.working_data, items, indices] = appFeatureSelection(app.data_C, app.RemoverawmeasurementsSwitch.Value, app.RemovecorrelatedSwitch.Value, autoSelectMethod, app.MaximumCorrelationEditField.Value, checks);
            end
            app.working_data.indices = indices;
            
            app.FeaturesListBox.Items = items;
            app.StartButton.Enable = true;
        end

        % Callback function
        function TestButtonPushed(app, event)
            app.TestButton.Enable='off';
            
            
            selection = uiconfirm(app.SSARUIFigure,"Running test configuration may take several minutes.","Confirm execution",'Icon','warning');
            
            if (strcmp(selection,'OK')==1)
                testAll(app.data_A);
            end

            
            app.TestButton.Enable='on';
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            selected = app.ModelsListBox.Value;
            if(~isempty(selected))
                selected = sscanf(selected{1}, "%d");
                selected = selected(1);
                switch app.ScenarioButtonGroup.SelectedObject.Text
                    case 'A'
                        app.Amodels(selected) = [];
                    case 'B'
                        app.Bmodels(selected) = [];
                    case 'C'
                        app.Cmodels(selected) = [];
                end
                
                app.update_models();
            end
        end

        % Button pushed function: RemoveWorstButton
        function RemoveWorstButtonPushed(app, event)
            switch app.ScenarioButtonGroup.SelectedObject.Text
                    case 'A'
                        models = app.Amodels;
                    case 'B'
                        models = app.Bmodels;
                    case 'C'
                        models = app.Cmodels;
            end
            
            threshold = app.ErrorLimitEditField.Value;
            toRemove = true(size(models));
            for i = 1:length(models)
                if models{i}.avg_error > threshold
                    toRemove(i) = false;
                end
            end
            
            switch app.ScenarioButtonGroup.SelectedObject.Text
                    case 'A'
                        app.Amodels = app.Amodels(toRemove);
                    case 'B'
                        app.Bmodels = app.Bmodels(toRemove);
                    case 'C'
                        app.Cmodels = app.Cmodels(toRemove);
            end
            
           app.update_models();
        end

        % Button pushed function: TestMultiModelButton
        function TestMultiModelButtonPushed(app, event)
            switch app.ScenarioButtonGroup.SelectedObject.Text
                    case 'A'
                        models = app.Amodels;
                        data = app.data_A;
                    case 'B'
                        models = app.Bmodels;
                        data = app.data_B;
                    case 'C'
                         models = app.Cmodels;
                         data = app.data_C;
            end
            checks = [strcmp(app.GadgetButtonGroup.SelectedObject.Text, 'Phone') strcmp(app.SensorButtonGroup.SelectedObject.Text, 'Accel')];
            [avg_error, std_error] = multiModelClassifier(data, models, checks);
            
            app.AvgErrorEditField.Value=avg_error;
            app.StdDevEditField.Value=std_error;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SSARUIFigure = uifigure('Visible', 'off');
            app.SSARUIFigure.Name = 'SSAR';
            app.TabGroup = uitabgroup(app.SSARUIFigure);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ScenarioButtonGroup = uibuttongroup(app.ScenarioTab);
            app.ScenarioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScenarioButtonGroupSelectionChanged, true);
            app.ScenarioButtonGroup.Title = 'Scenario';
            app.AButton = uitogglebutton(app.ScenarioButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.ScenarioButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uitogglebutton(app.ScenarioButtonGroup);
            app.CButton.Text = 'C';
            app.ClassifierADropDownLabel = uilabel(app.ScenarioTab);
            app.ClassifierADropDownLabel.Text = 'Classifier A';
            app.ClassifierADropDown = uidropdown(app.ScenarioTab);
            app.ClassifierADropDown.Items = {'Bayes', 'Euclidean', 'Fisher LDA', 'K-means', 'Mahalanobis', 'SVM'};
            app.ClassifierADropDown.Value = 'Euclidean';
            app.ClassifierBDropDownLabel = uilabel(app.ScenarioTab);
            app.ClassifierBDropDownLabel.Text = 'Classifier B';
            app.ClassifierBDropDown = uidropdown(app.ScenarioTab);
            app.ClassifierBDropDown.Items = {'Bayes', 'Euclidean', 'K-means', 'Mahalanobis', 'SVM'};
            app.ClassifierBDropDown.Value = 'Euclidean';
            app.ClassifierCDropDownLabel = uilabel(app.ScenarioTab);
            app.ClassifierCDropDownLabel.Text = 'Classifier C';
            app.ClassifierCDropDown = uidropdown(app.ScenarioTab);
            app.ClassifierCDropDown.Items = {'Bayes', 'Euclidean', 'K-means', 'Mahalanobis', 'SVM'};
            app.ClassifierCDropDown.Value = 'Euclidean';
            app.DatasetpartitioningEditFieldLabel = uilabel(app.ScenarioTab);
            app.DatasetpartitioningEditFieldLabel.Text = 'Dataset partitioning %';
            app.DatasetpartitioningEditField = uieditfield(app.ScenarioTab, 'numeric');
            app.DatasetpartitioningEditField.Limits = [0 1];
            app.DatasetpartitioningEditField.ValueDisplayFormat = '%1.2f';
            app.DatasetpartitioningEditField.Value = 0.5;
            app.ModelsListBoxLabel = uilabel(app.ScenarioTab);
            app.ModelsListBoxLabel.Text = 'Models';
            app.ModelsListBox = uilistbox(app.ScenarioTab);
            app.ModelsListBox.Items = {};
            app.ModelsListBox.Multiselect = 'on';
            app.ModelsListBox.Value = {};
            app.RemoveButton = uibutton(app.ScenarioTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.TestMultiModelButton = uibutton(app.ScenarioTab, 'push');
            app.TestMultiModelButton.ButtonPushedFcn = createCallbackFcn(app, @TestMultiModelButtonPushed, true);
            app.TestMultiModelButton.Enable = 'off';
            app.TestMultiModelButton.Text = 'Test Multi Model';
            app.RemoveWorstButton = uibutton(app.ScenarioTab, 'push');
            app.RemoveWorstButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWorstButtonPushed, true);
            app.RemoveWorstButton.Text = 'Remove Worst';
            app.ErrorLimitLabel = uilabel(app.ScenarioTab);
            app.ErrorLimitLabel.Text = 'Error Limit %';
            app.ErrorLimitEditField = uieditfield(app.ScenarioTab, 'numeric');
            app.ErrorLimitEditField.Limits = [0 1];
            app.ErrorLimitEditField.ValueDisplayFormat = '%0.3f';
            app.ErrorLimitEditField.Value = 0.035;
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.RemoverawmeasurementsSwitchLabel = uilabel(app.FeatureSelectionTab);
            app.RemoverawmeasurementsSwitchLabel.Text = 'Remove raw measurements';
            app.RemoverawmeasurementsSwitch = uiswitch(app.FeatureSelectionTab, 'slider');
            app.RemovecorrelatedSwitchLabel = uilabel(app.FeatureSelectionTab);
            app.RemovecorrelatedSwitchLabel.Text = 'Remove correlated';
            app.RemovecorrelatedSwitch = uiswitch(app.FeatureSelectionTab, 'slider');
            app.MaximumCorrelationEditFieldLabel = uilabel(app.FeatureSelectionTab);
            app.MaximumCorrelationEditFieldLabel.Text = 'Maximum Correlation';
            app.MaximumCorrelationEditField = uieditfield(app.FeatureSelectionTab, 'numeric');
            app.MaximumCorrelationEditField.Limits = [0 1];
            app.MaximumCorrelationEditField.ValueDisplayFormat = '%1.2f';
            app.MaximumCorrelationEditField.Value = 0.5;
            app.FeaturesListBoxLabel = uilabel(app.FeatureSelectionTab);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.FeatureSelectionTab);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {};
            app.UpdateButton = uibutton(app.FeatureSelectionTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.GadgetButtonGroup = uibuttongroup(app.FeatureSelectionTab);
            app.GadgetButtonGroup.Title = 'Gadget';
            app.PhoneButton = uitogglebutton(app.GadgetButtonGroup);
            app.PhoneButton.Text = 'Phone';
            app.PhoneButton.Value = true;
            app.WatchButton = uitogglebutton(app.GadgetButtonGroup);
            app.WatchButton.Text = 'Watch';
            app.SensorButtonGroup = uibuttongroup(app.FeatureSelectionTab);
            app.SensorButtonGroup.Title = 'Sensor';
            app.AccelButton = uitogglebutton(app.SensorButtonGroup);
            app.AccelButton.Text = 'Accel';
            app.AccelButton.Value = true;
            app.GyroButton = uitogglebutton(app.SensorButtonGroup);
            app.GyroButton.Text = 'Gyro';
            app.FeatureRankingDropDownLabel = uilabel(app.FeatureSelectionTab);
            app.FeatureRankingDropDownLabel.Text = 'Feature Ranking';
            app.FeatureRankingDropDown = uidropdown(app.FeatureSelectionTab);
            app.FeatureRankingDropDown.Items = {'KruskalWallis', 'ROC'};
            app.FeatureRankingDropDown.Value = 'KruskalWallis';
            app.FeatureReductionTab = uitab(app.TabGroup);
            app.FeatureReductionTab.Title = 'Feature Reduction';
            app.PCAVarianceEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.PCAVarianceEditFieldLabel.Text = 'PCA Variance';
            app.PCAVarianceEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.PCAVarianceEditField.Limits = [0 1];
            app.PCAVarianceEditField.ValueDisplayFormat = '%1.2f';
            app.PCAVarianceEditField.Value = 0.95;
            app.LDAFeaturesEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.LDAFeaturesEditFieldLabel.Text = '# LDA Features';
            app.LDAFeaturesEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.LDAFeaturesEditField.Limits = [1 Inf];
            app.LDAFeaturesEditField.RoundFractionalValues = 'on';
            app.LDAFeaturesEditField.ValueDisplayFormat = '%.0f';
            app.LDAFeaturesEditField.Value = 10;
            app.MethodDropDownLabel = uilabel(app.FeatureReductionTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.FeatureReductionTab);
            app.MethodDropDown.Items = {'None', 'LDA', 'PCA %var', 'PCA #feat'};
            app.MethodDropDown.Value = 'None';
            app.PCAFeaturesEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.PCAFeaturesEditFieldLabel.Text = '# PCA Features';
            app.PCAFeaturesEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.PCAFeaturesEditField.Limits = [1 Inf];
            app.PCAFeaturesEditField.RoundFractionalValues = 'on';
            app.PCAFeaturesEditField.ValueDisplayFormat = '%.0f';
            app.PCAFeaturesEditField.Value = 10;
            app.DecisionTreeTab = uitab(app.TabGroup);
            app.DecisionTreeTab.Title = 'Decision Tree';
            app.StartButton = uibutton(app.SSARUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = {'Start'; ''};
            app.ExitButton = uibutton(app.SSARUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes = uiaxes(app.SSARUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AvgErrorEditFieldLabel = uilabel(app.SSARUIFigure);
            app.AvgErrorEditFieldLabel.Text = 'Avg. Error';
            app.AvgErrorEditField = uieditfield(app.SSARUIFigure, 'numeric');
            app.StdDevEditFieldLabel = uilabel(app.SSARUIFigure);
            app.StdDevEditFieldLabel.Text = 'Std. Dev.';
            app.StdDevEditField = uieditfield(app.SSARUIFigure, 'numeric');
            % Show the figure after all components are created
            app.SSARUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SSARUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SSARUIFigure)
        end
    end
end